classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Step1GetDataGetTrainingandTestingDataPanel  matlab.ui.container.Panel
        DoItButton                      matlab.ui.control.Button
        InspectOption1ButtonGroup_Data  matlab.ui.container.ButtonGroup
        ScenarioAButton                 matlab.ui.control.RadioButton
        ScenarioBButton                 matlab.ui.control.RadioButton
        ScenarioCButton                 matlab.ui.control.RadioButton
        InspectButton                   matlab.ui.control.Button
        SelectFileListBox_4Label        matlab.ui.control.Label
        SelectFileListBox_Data          matlab.ui.control.ListBox
        ConfirmButton_Data              matlab.ui.control.Button
        InspectOption2ButtonGroup_Data  matlab.ui.container.ButtonGroup
        OriginalDataButton              matlab.ui.control.RadioButton
        TrnTstDataButton                matlab.ui.control.RadioButton
        Step2ExploratoryDataAnalysisFeatureSelectionPanel  matlab.ui.container.Panel
        DoItButton_EDA                  matlab.ui.control.Button
        InspectOption1ButtonGroup_EDA   matlab.ui.container.ButtonGroup
        ScenarioAButton_EDA             matlab.ui.control.RadioButton
        ScenarioBButton_EDA             matlab.ui.control.RadioButton
        ScenarioCButton_EDA             matlab.ui.control.RadioButton
        ConfirmButton_EDA               matlab.ui.control.Button
        SelectFileListBox_5Label        matlab.ui.control.Label
        SelectFileListBox_EDA           matlab.ui.control.ListBox
        InspectOption2ButtonGroup_EDA   matlab.ui.container.ButtonGroup
        FeaturesRecordButton            matlab.ui.control.RadioButton
        DescriptiveStatsButton          matlab.ui.control.RadioButton
        VisualizationButton             matlab.ui.control.RadioButton
        InspectButton_EDA               matlab.ui.control.Button
        InspectOption2ButtonGroup_EDA_2  matlab.ui.container.ButtonGroup
        apButton                        matlab.ui.control.RadioButton
        awButton                        matlab.ui.control.RadioButton
        gpButton                        matlab.ui.control.RadioButton
        gwButton                        matlab.ui.control.RadioButton
        InspectOption2ButtonGroup_EDA_3  matlab.ui.container.ButtonGroup
        HistogtamButton                 matlab.ui.control.RadioButton
        BoxPlotButton                   matlab.ui.control.RadioButton
        PatternButton                   matlab.ui.control.RadioButton
        TrendButton                     matlab.ui.control.RadioButton
        InspectOption2ButtonGroup_EDA_7  matlab.ui.container.ButtonGroup
        BeforeButton                    matlab.ui.control.RadioButton
        AfterButton                     matlab.ui.control.RadioButton
        Step3FeatureReductionPanel      matlab.ui.container.Panel
        DoItButton_FR                   matlab.ui.control.Button
        InspectOption1ButtonGroup_FR    matlab.ui.container.ButtonGroup
        ScenarioAButton_FR              matlab.ui.control.RadioButton
        ScenarioBButton_FR              matlab.ui.control.RadioButton
        ScenarioCButton_FR              matlab.ui.control.RadioButton
        ConfirmButton_FR                matlab.ui.control.Button
        SelectFileListBox_3Label        matlab.ui.control.Label
        SelectFileListBox_FR            matlab.ui.control.ListBox
        InspectButton_4                 matlab.ui.control.Button
        InspectOption2ButtonGroup_EDA_6  matlab.ui.container.ButtonGroup
        PCAVarienceEigvalButton         matlab.ui.control.RadioButton
        PCALDACompareButton             matlab.ui.control.RadioButton
        Step4ClassificationPanel        matlab.ui.container.Panel
        DoItButton_C                    matlab.ui.control.Button
        InspectOption1ButtonGroup_C     matlab.ui.container.ButtonGroup
        ScenarioAButton_C               matlab.ui.control.RadioButton
        ScenarioBButton_C               matlab.ui.control.RadioButton
        ScenarioCButton_C               matlab.ui.control.RadioButton
        ConfirmButton_C                 matlab.ui.control.Button
        SelectFileListBox_2Label        matlab.ui.control.Label
        SelectFileListBox_C             matlab.ui.control.ListBox
        InspectButton_C                 matlab.ui.control.Button
        InspectOption1ButtonGroup_C_2   matlab.ui.container.ButtonGroup
        BayesButton                     matlab.ui.control.RadioButton
        LDAButton                       matlab.ui.control.RadioButton
        KNNButton                       matlab.ui.control.RadioButton
        MDCButton                       matlab.ui.control.RadioButton
        SVMButton                       matlab.ui.control.RadioButton
        Step5FindtheBestClassifierPanel  matlab.ui.container.Panel
        DoItButton_BC                   matlab.ui.control.Button
        InspectButton_BC                matlab.ui.control.Button
        PathIndicatorLabel              matlab.ui.control.Label
        currentPathLabel                matlab.ui.control.Label
        FileTypeLabel                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConfirmButton_Data
        function ConfirmButton_DataPushed(app, event)
            scen = 'A';
            if app.ScenarioAButton.Value
                scen = 'A';
            else 
                if app.ScenarioBButton.Value
                   scen = 'B';
                else
                    if app.ScenarioCButton.Value
                        scen = 'C';
                    end
                end                
            end
            dataFile = 'OriginalData';
            if app.OriginalDataButton.Value
                dataFile = 'OriginalData';
            else 
                if app.TrnTstDataButton.Value
                   dataFile = 'TrainingTesting';
                end                
            end
            selPath = ['./Data/' scen '/' dataFile '/'];
            %Dir all *.mat in selected folder.
            files = dir(fullfile(selPath, '*.mat'));
            %Populate listbox with file names:
            app.SelectFileListBox_Data.Items = {files(:).name};
            app.currentPathLabel.Text = selPath;
        end

        % Button pushed function: InspectButton
        function InspectButtonPushed(app, event)
            value = app.SelectFileListBox_Data.Value;
            selected_file = fullfile(app.currentPathLabel.Text, value); %Get the full path of selected file.
            ll = importdata(selected_file); %Change the code to load selected_file
            disp(ll);
            %app.currentPathLabel.Text = selected_file;
            clear selected_file;
        end

        % Button pushed function: ConfirmButton_EDA
        function ConfirmButton_EDAPushed(app, event)
            fileType = '*.mat';
            scen = 'A';
            if app.ScenarioAButton_EDA.Value
                scen = 'A';
            else 
                if app.ScenarioBButton_EDA.Value
                   scen = 'B';
                else
                    if app.ScenarioCButton_EDA.Value
                        scen = 'C';
                    end
                end                
            end
            pathName = '';
            if app.FeaturesRecordButton.Value
                pathName = ['./FeatureSelection/' scen '/RankStats/'];
            else 
                if app.DescriptiveStatsButton.Value
                    pathName = ['./EDA/' scen '/DescriptiveStats/'];
                    fileType = '*.xlsx';
                end                
            end
            
            if app.VisualizationButton.Value
               type = 'ap';
               plotName = 'Histogram';
               timeName = 'Before';
               if app.apButton.Value
                    type = 'ap';
               end
               if app.awButton.Value
                    type = 'aw';
               end
               if app.gwButton.Value
                    type = 'gw';
               end
               if app.gpButton.Value
                    type = 'gp';
               end
               
               if app.HistogtamButton.Value
                    plotName = 'Histogram';
               end
               if app.BoxPlotButton.Value
                    plotName = 'Boxplot';
               end
               if app.PatternButton.Value
                    plotName = 'Pattern';
               end
               if app.TrendButton.Value
                    plotName = 'Trend';
               end
               
               if app.BeforeButton.Value
                    timeName = 'Before';
               end
               if app.AfterButton.Value
                    timeName = 'After';
               end
               pathName = ['./EDA/Visualization/' type '/' plotName '/' timeName '/'];
               fileType = '*.fig';
           end
            %Dir all *.mat in selected folder.
            files = dir(fullfile(pathName, fileType));
            %Populate listbox with file names:
            app.SelectFileListBox_EDA.Items = {files(:).name};
            app.currentPathLabel.Text = pathName;
            app.FileTypeLabel.Text = fileType;
        end

        % Button pushed function: InspectButton_EDA
        function InspectButton_EDAPushed(app, event)
            value = app.SelectFileListBox_EDA.Value;
            selected_file = fullfile(app.currentPathLabel.Text, value); %Get the full path of selected file.
            %open(selected_file); %Change the code to load selected_file
            if strcmp(app.FileTypeLabel.Text, '*.fig')
                openfig(selected_file);
                clear selected_file;
            else
                if strcmp(app.FileTypeLabel.Text, '*.xlsx')
                    open(selected_file);
                    clear selected_file;
                else
                    if strcmp(app.FileTypeLabel.Text, '*.mat')
                        llok = importdata(selected_file);
                        disp(llok);                      
                    end
                end
                
            end
            %app.currentPathLabel.Text = selected_fize;
        end

        % Button pushed function: ConfirmButton_FR
        function ConfirmButton_FRPushed(app, event)
            fileType = '*.mat';
            scen = 'A';
            if app.ScenarioAButton_FR.Value
                scen = 'A';
            else 
                if app.ScenarioBButton_FR.Value
                   scen = 'B';
                else
                    if app.ScenarioCButton_FR.Value
                        scen = 'C';
                    end
                end                
            end
            pathName = '';
            if app.PCAVarienceEigvalButton.Value
                pathName = ['./FeatureReduction/' scen '/Visualization/Eigval'];
                fileType = '*.fig';
            else 
                if app.PCALDACompareButton.Value
                    pathName = ['./FeatureReduction/' scen '/Visualization/PCALDACompare'];
                    fileType = '*.fig';
                end                
            end
            
            %Dir all *.mat in selected folder.
            files = dir(fullfile(pathName, fileType));
            %Populate listbox with file names:
            app.SelectFileListBox_FR.Items = {files(:).name};
            app.currentPathLabel.Text = pathName;
            app.FileTypeLabel.Text = fileType;
        end

        % Button pushed function: InspectButton_4
        function InspectButton_4Pushed(app, event)
            value = app.SelectFileListBox_FR.Value;
            selected_file = fullfile(app.currentPathLabel.Text, value); %Get the full path of selected file.
            %open(selected_file); %Change the code to load selected_file
            strcmp(app.FileTypeLabel.Text, '*.fig')
            openfig(selected_file);
        end

        % Button pushed function: ConfirmButton_C
        function ConfirmButton_CPushed(app, event)
            fileType = '*.mat';
            scen = 'A';
            if app.ScenarioAButton_C.Value
                scen = 'A';
            else 
                if app.ScenarioBButton_C.Value
                   scen = 'B';
                else
                    if app.ScenarioCButton_C.Value
                        scen = 'C';
                    end
                end                
            end
            
            pathName = '';
            if app.BayesButton.Value
                pathName = ['./Classifier/' scen '/Bayes/Record/'];
            end 
            if app.LDAButton.Value
                pathName = ['./Classifier/' scen '/FisherLDA/Record/'];
            end                
            if app.KNNButton.Value
                pathName = ['./Classifier/' scen '/KNN/Record/'];
            end 
            if app.MDCButton.Value
                pathName = ['./Classifier/' scen '/MDC/Record/'];
            end        
            if app.SVMButton.Value
                pathName = ['./Classifier/' scen '/SVM/Record/'];
            end 
            
            %Dir all *.mat in selected folder.
            files = dir(fullfile(pathName, fileType));
            %Populate listbox with file names:
            app.SelectFileListBox_C.Items = {files(:).name};
            app.currentPathLabel.Text = pathName;
            app.FileTypeLabel.Text = fileType;
        end

        % Button pushed function: InspectButton_C
        function InspectButton_CPushed(app, event)
            value = app.SelectFileListBox_C.Value;
            selected_file = fullfile(app.currentPathLabel.Text, value);
            llok = importdata(selected_file);
            disp(llok);   
        end

        % Button pushed function: DoItButton
        function DoItButtonPushed(app, event)
            GetandSaveDataForAllCases();
            GetTrnandTstDataForAllCases();
        end

        % Button pushed function: DoItButton_EDA
        function DoItButton_EDAPushed(app, event)
            FeatureSelection();
        end

        % Button pushed function: DoItButton_FR
        function DoItButton_FRPushed(app, event)
            FeatureReduction();
        end

        % Button pushed function: DoItButton_C
        function DoItButton_CPushed(app, event)
            Classification();
        end

        % Button pushed function: DoItButton_BC
        function DoItButton_BCPushed(app, event)
            findBestClassifiers();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Step1GetDataGetTrainingandTestingDataPanel = uipanel(app.UIFigure);
            app.Step1GetDataGetTrainingandTestingDataPanel.Title = 'Step 1 : Get Data & Get Training and Testing Data';
            app.DoItButton = uibutton(app.Step1GetDataGetTrainingandTestingDataPanel, 'push');
            app.DoItButton.ButtonPushedFcn = createCallbackFcn(app, @DoItButtonPushed, true);
            app.DoItButton.Text = 'Do It';
            app.InspectOption1ButtonGroup_Data = uibuttongroup(app.Step1GetDataGetTrainingandTestingDataPanel);
            app.InspectOption1ButtonGroup_Data.Title = 'Inspect Option 1:';
            app.ScenarioAButton = uiradiobutton(app.InspectOption1ButtonGroup_Data);
            app.ScenarioAButton.Text = 'Scenario A';
            app.ScenarioAButton.Value = true;
            app.ScenarioBButton = uiradiobutton(app.InspectOption1ButtonGroup_Data);
            app.ScenarioBButton.Text = 'Scenario B';
            app.ScenarioCButton = uiradiobutton(app.InspectOption1ButtonGroup_Data);
            app.ScenarioCButton.Text = 'Scenario C';
            app.InspectButton = uibutton(app.Step1GetDataGetTrainingandTestingDataPanel, 'push');
            app.InspectButton.ButtonPushedFcn = createCallbackFcn(app, @InspectButtonPushed, true);
            app.InspectButton.Text = 'Inspect';
            app.SelectFileListBox_4Label = uilabel(app.Step1GetDataGetTrainingandTestingDataPanel);
            app.SelectFileListBox_4Label.Text = 'Select File:';
            app.SelectFileListBox_Data = uilistbox(app.Step1GetDataGetTrainingandTestingDataPanel);
            app.ConfirmButton_Data = uibutton(app.Step1GetDataGetTrainingandTestingDataPanel, 'push');
            app.ConfirmButton_Data.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButton_DataPushed, true);
            app.ConfirmButton_Data.Text = 'Confirm';
            app.InspectOption2ButtonGroup_Data = uibuttongroup(app.Step1GetDataGetTrainingandTestingDataPanel);
            app.InspectOption2ButtonGroup_Data.Title = 'Inspect Option 2:';
            app.OriginalDataButton = uiradiobutton(app.InspectOption2ButtonGroup_Data);
            app.OriginalDataButton.Text = 'Original Data';
            app.OriginalDataButton.Value = true;
            app.TrnTstDataButton = uiradiobutton(app.InspectOption2ButtonGroup_Data);
            app.TrnTstDataButton.Text = 'Trn & Tst Data';
            app.Step2ExploratoryDataAnalysisFeatureSelectionPanel = uipanel(app.UIFigure);
            app.Step2ExploratoryDataAnalysisFeatureSelectionPanel.Title = 'Step 2: Exploratory Data Analysis && Feature Selection';
            app.DoItButton_EDA = uibutton(app.Step2ExploratoryDataAnalysisFeatureSelectionPanel, 'push');
            app.DoItButton_EDA.ButtonPushedFcn = createCallbackFcn(app, @DoItButton_EDAPushed, true);
            app.DoItButton_EDA.Text = 'Do It';
            app.InspectOption1ButtonGroup_EDA = uibuttongroup(app.Step2ExploratoryDataAnalysisFeatureSelectionPanel);
            app.InspectOption1ButtonGroup_EDA.Title = 'Inspect Option 1:';
            app.ScenarioAButton_EDA = uiradiobutton(app.InspectOption1ButtonGroup_EDA);
            app.ScenarioAButton_EDA.Text = 'Scenario A';
            app.ScenarioAButton_EDA.Value = true;
            app.ScenarioBButton_EDA = uiradiobutton(app.InspectOption1ButtonGroup_EDA);
            app.ScenarioBButton_EDA.Text = 'Scenario B';
            app.ScenarioCButton_EDA = uiradiobutton(app.InspectOption1ButtonGroup_EDA);
            app.ScenarioCButton_EDA.Text = 'Scenario C';
            app.ConfirmButton_EDA = uibutton(app.Step2ExploratoryDataAnalysisFeatureSelectionPanel, 'push');
            app.ConfirmButton_EDA.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButton_EDAPushed, true);
            app.ConfirmButton_EDA.Text = 'Confirm';
            app.SelectFileListBox_5Label = uilabel(app.Step2ExploratoryDataAnalysisFeatureSelectionPanel);
            app.SelectFileListBox_5Label.Text = 'Select File:';
            app.SelectFileListBox_EDA = uilistbox(app.Step2ExploratoryDataAnalysisFeatureSelectionPanel);
            app.InspectOption2ButtonGroup_EDA = uibuttongroup(app.Step2ExploratoryDataAnalysisFeatureSelectionPanel);
            app.InspectOption2ButtonGroup_EDA.Title = 'Inspect Option 2:';
            app.FeaturesRecordButton = uiradiobutton(app.InspectOption2ButtonGroup_EDA);
            app.FeaturesRecordButton.Text = 'Features Record';
            app.FeaturesRecordButton.Value = true;
            app.DescriptiveStatsButton = uiradiobutton(app.InspectOption2ButtonGroup_EDA);
            app.DescriptiveStatsButton.Text = 'Descriptive Stats';
            app.VisualizationButton = uiradiobutton(app.InspectOption2ButtonGroup_EDA);
            app.VisualizationButton.Text = 'Visualization';
            app.InspectButton_EDA = uibutton(app.Step2ExploratoryDataAnalysisFeatureSelectionPanel, 'push');
            app.InspectButton_EDA.ButtonPushedFcn = createCallbackFcn(app, @InspectButton_EDAPushed, true);
            app.InspectButton_EDA.Text = 'Inspect';
            app.InspectOption2ButtonGroup_EDA_2 = uibuttongroup(app.Step2ExploratoryDataAnalysisFeatureSelectionPanel);
            app.InspectOption2ButtonGroup_EDA_2.Title = 'Visualization Option 1 :';
            app.apButton = uiradiobutton(app.InspectOption2ButtonGroup_EDA_2);
            app.apButton.Text = 'ap';
            app.apButton.Value = true;
            app.awButton = uiradiobutton(app.InspectOption2ButtonGroup_EDA_2);
            app.awButton.Text = 'aw';
            app.gpButton = uiradiobutton(app.InspectOption2ButtonGroup_EDA_2);
            app.gpButton.Text = 'gp';
            app.gwButton = uiradiobutton(app.InspectOption2ButtonGroup_EDA_2);
            app.gwButton.Text = 'gw';
            app.InspectOption2ButtonGroup_EDA_3 = uibuttongroup(app.Step2ExploratoryDataAnalysisFeatureSelectionPanel);
            app.InspectOption2ButtonGroup_EDA_3.Title = 'Visualization Option 2 :';
            app.HistogtamButton = uiradiobutton(app.InspectOption2ButtonGroup_EDA_3);
            app.HistogtamButton.Text = 'Histogtam';
            app.HistogtamButton.Value = true;
            app.BoxPlotButton = uiradiobutton(app.InspectOption2ButtonGroup_EDA_3);
            app.BoxPlotButton.Text = 'BoxPlot';
            app.PatternButton = uiradiobutton(app.InspectOption2ButtonGroup_EDA_3);
            app.PatternButton.Text = 'Pattern';
            app.TrendButton = uiradiobutton(app.InspectOption2ButtonGroup_EDA_3);
            app.TrendButton.Text = 'Trend';
            app.InspectOption2ButtonGroup_EDA_7 = uibuttongroup(app.Step2ExploratoryDataAnalysisFeatureSelectionPanel);
            app.InspectOption2ButtonGroup_EDA_7.Title = 'Visualization Option 3 :';
            app.BeforeButton = uiradiobutton(app.InspectOption2ButtonGroup_EDA_7);
            app.BeforeButton.Text = 'Before';
            app.BeforeButton.Value = true;
            app.AfterButton = uiradiobutton(app.InspectOption2ButtonGroup_EDA_7);
            app.AfterButton.Text = 'After';
            app.Step3FeatureReductionPanel = uipanel(app.UIFigure);
            app.Step3FeatureReductionPanel.Title = 'Step 3: Feature Reduction';
            app.DoItButton_FR = uibutton(app.Step3FeatureReductionPanel, 'push');
            app.DoItButton_FR.ButtonPushedFcn = createCallbackFcn(app, @DoItButton_FRPushed, true);
            app.DoItButton_FR.Text = 'Do It';
            app.InspectOption1ButtonGroup_FR = uibuttongroup(app.Step3FeatureReductionPanel);
            app.InspectOption1ButtonGroup_FR.Title = 'Inspect Option 1:';
            app.ScenarioAButton_FR = uiradiobutton(app.InspectOption1ButtonGroup_FR);
            app.ScenarioAButton_FR.Text = 'Scenario A';
            app.ScenarioAButton_FR.Value = true;
            app.ScenarioBButton_FR = uiradiobutton(app.InspectOption1ButtonGroup_FR);
            app.ScenarioBButton_FR.Text = 'Scenario B';
            app.ScenarioCButton_FR = uiradiobutton(app.InspectOption1ButtonGroup_FR);
            app.ScenarioCButton_FR.Text = 'Scenario C';
            app.ConfirmButton_FR = uibutton(app.Step3FeatureReductionPanel, 'push');
            app.ConfirmButton_FR.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButton_FRPushed, true);
            app.ConfirmButton_FR.Text = 'Confirm';
            app.SelectFileListBox_3Label = uilabel(app.Step3FeatureReductionPanel);
            app.SelectFileListBox_3Label.Text = 'Select File:';
            app.SelectFileListBox_FR = uilistbox(app.Step3FeatureReductionPanel);
            app.InspectButton_4 = uibutton(app.Step3FeatureReductionPanel, 'push');
            app.InspectButton_4.ButtonPushedFcn = createCallbackFcn(app, @InspectButton_4Pushed, true);
            app.InspectButton_4.Text = 'Inspect';
            app.InspectOption2ButtonGroup_EDA_6 = uibuttongroup(app.Step3FeatureReductionPanel);
            app.InspectOption2ButtonGroup_EDA_6.Title = 'Visualization Option 2 :';
            app.PCAVarienceEigvalButton = uiradiobutton(app.InspectOption2ButtonGroup_EDA_6);
            app.PCAVarienceEigvalButton.Text = 'PCA Varience & Eigval';
            app.PCAVarienceEigvalButton.Value = true;
            app.PCALDACompareButton = uiradiobutton(app.InspectOption2ButtonGroup_EDA_6);
            app.PCALDACompareButton.Text = 'PCA LDA Compare';
            app.Step4ClassificationPanel = uipanel(app.UIFigure);
            app.Step4ClassificationPanel.Title = 'Step 4: Classification';
            app.DoItButton_C = uibutton(app.Step4ClassificationPanel, 'push');
            app.DoItButton_C.ButtonPushedFcn = createCallbackFcn(app, @DoItButton_CPushed, true);
            app.DoItButton_C.Text = 'Do It';
            app.InspectOption1ButtonGroup_C = uibuttongroup(app.Step4ClassificationPanel);
            app.InspectOption1ButtonGroup_C.Title = 'Inspect Option 1:';
            app.ScenarioAButton_C = uiradiobutton(app.InspectOption1ButtonGroup_C);
            app.ScenarioAButton_C.Text = 'Scenario A';
            app.ScenarioAButton_C.Value = true;
            app.ScenarioBButton_C = uiradiobutton(app.InspectOption1ButtonGroup_C);
            app.ScenarioBButton_C.Text = 'Scenario B';
            app.ScenarioCButton_C = uiradiobutton(app.InspectOption1ButtonGroup_C);
            app.ScenarioCButton_C.Text = 'Scenario C';
            app.ConfirmButton_C = uibutton(app.Step4ClassificationPanel, 'push');
            app.ConfirmButton_C.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButton_CPushed, true);
            app.ConfirmButton_C.Text = 'Confirm';
            app.SelectFileListBox_2Label = uilabel(app.Step4ClassificationPanel);
            app.SelectFileListBox_2Label.Text = 'Select File:';
            app.SelectFileListBox_C = uilistbox(app.Step4ClassificationPanel);
            app.InspectButton_C = uibutton(app.Step4ClassificationPanel, 'push');
            app.InspectButton_C.ButtonPushedFcn = createCallbackFcn(app, @InspectButton_CPushed, true);
            app.InspectButton_C.Text = 'Inspect';
            app.InspectOption1ButtonGroup_C_2 = uibuttongroup(app.Step4ClassificationPanel);
            app.InspectOption1ButtonGroup_C_2.Title = 'Model Option 1:';
            app.BayesButton = uiradiobutton(app.InspectOption1ButtonGroup_C_2);
            app.BayesButton.Text = 'Bayes';
            app.BayesButton.Value = true;
            app.LDAButton = uiradiobutton(app.InspectOption1ButtonGroup_C_2);
            app.LDAButton.Text = 'FisherLDA';
            app.KNNButton = uiradiobutton(app.InspectOption1ButtonGroup_C_2);
            app.KNNButton.Text = 'KNN';
            app.MDCButton = uiradiobutton(app.InspectOption1ButtonGroup_C_2);
            app.MDCButton.Text = 'MDC';
            app.SVMButton = uiradiobutton(app.InspectOption1ButtonGroup_C_2);
            app.SVMButton.Text = 'SVM';
            app.Step5FindtheBestClassifierPanel = uipanel(app.UIFigure);
            app.Step5FindtheBestClassifierPanel.Title = 'Step 5: Find the Best Classifier';
            app.DoItButton_BC = uibutton(app.Step5FindtheBestClassifierPanel, 'push');
            app.DoItButton_BC.ButtonPushedFcn = createCallbackFcn(app, @DoItButton_BCPushed, true);
            app.DoItButton_BC.Text = 'Do It';
            app.InspectButton_BC = uibutton(app.Step5FindtheBestClassifierPanel, 'push');
            app.InspectButton_BC.Text = 'Inspect';
            app.PathIndicatorLabel = uilabel(app.UIFigure);
            app.PathIndicatorLabel.Text = 'Path Indicator:';
            app.currentPathLabel = uilabel(app.UIFigure);
            app.currentPathLabel.Text = 'current Path';
            app.FileTypeLabel = uilabel(app.UIFigure);
            app.FileTypeLabel.Text = 'FileType';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end